classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GameSetup                   matlab.ui.control.Table
        Image                       matlab.ui.control.Image
        MovePieceSpinner            matlab.ui.control.Spinner
        MovePieceSpinnerLabel       matlab.ui.control.Label
        MoveButton                  matlab.ui.control.Button
        DicerollEditField           matlab.ui.control.NumericEditField
        DicerollEditFieldLabel      matlab.ui.control.Label
        Scoreplayer2EditField       matlab.ui.control.NumericEditField
        Scoreplayer2EditFieldLabel  matlab.ui.control.Label
        Scoreplayer1EditField       matlab.ui.control.NumericEditField
        Scoreplayer1EditFieldLabel  matlab.ui.control.Label
        GameMessageTextArea         matlab.ui.control.TextArea
        GameMessageTextAreaLabel    matlab.ui.control.Label
        StartGameButton             matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ledArduino
        dumpArduino
        towerPort % Description
        moverPort % Description
        swapPort % Description
        steps_for_opening = -674
        steps_for_closing = 674
        steps_for_raising = 4000
        steps_for_lowering = -5000
        steps_for_swapping = 260
    end
    methods (Access = private)

        function name = ternaryOperator(~, red, yellow)
            if red == 1
                name = 'red';
            elseif yellow == 1
                name='yellow';
            else
                name='0';
            end
        end

        function dice = rollDice(~)
            cam = webcam(2);
            preview(cam);

            for countdown=5:-1:1
                disp(countdown);
                pause(1);
            end
            image = snapshot(cam);
            imshow(image);


            crop=[210   210    122   234];

            croppedImage=imcrop(image,crop);
            imtool(croppedImage);


            g_channel=croppedImage(:,:,2);
            r_channel=croppedImage(:,:,1);
            b_channel=croppedImage(:,:,3);

            gr_ratio=double(g_channel)./double(r_channel);
            gb_ratio=double(g_channel)./double(b_channel);
            gr_ratio(isnan(gr_ratio))=0;
            gb_ratio(isnan(gb_ratio))=0;

            imshow(croppedImage)
            g_bin = gr_ratio<=0.7 & gb_ratio <=0.7 & g_channel <=200 & r_channel<=100 & b_channel<=200;
            imshow(g_bin)


            ImprovedPic=bwareaopen(g_bin,15);
            filledHoles=imfill(g_bin,'holes');
            Improved2=bwareaopen(filledHoles,15); % gets rid of 15pixels and smaller
            imshow(Improved2)

            tableOfProp=regionprops('table',Improved2,'BoundingBox');

            dice=height(tableOfProp);

        end

        %stepper1 4-5-6-7
        function moveClaw(~, s, action)
            pause(5);                 
            disp('move claw')
            Multiple_Stepper_String = append("1,",int2str(0),",","2,",int2str(action));            
            write(s,Multiple_Stepper_String,'string');

            pause(8);
        end        
        %stepper2 8-9-10-11
        function moveBoom(~, s, action)
            pause(5);     
            disp('move boom')
            Multiple_Stepper_String = append("1,",int2str(action),",","2,",int2str(0));            
            write(s,Multiple_Stepper_String,'string');

            pause(8);
        end 

        %s: serial port
        function moveTower2Pick(~, s, pickLocation)
            pause(5);

            if (pickLocation <= 6)
                distance = 2052/12 * (pickLocation-1);
            else
                distance = -2052/12 * (12 - (pickLocation-1));
            end
            write(s,int2str(distance),'string');
            pause(12);
        end
        
        function moveTower2Drop(~, s, dropLocation)
            pause(5);

            if (dropLocation <= 6)
                distance = 2052/12 * (dropLocation-1);
            else
                distance = -2052/12 * (5 + (12 - dropLocation));
            end
            write(s,int2str(distance),'string');
            pause(12);
        end

        function moveTowerHome(~, s, currentLocation)
            pause(5);

            if (currentLocation <= 6)
                distance = -2052/12 * (currentLocation-1);
            else
                distance = 2052/12 * (12 - currentLocation);
            end
            write(s,int2str(distance),'string');
            pause(12);
        end        
        

        function toSwap(~, s, spot)
            pause(5)
            if spot==3
                disp('swap 3-4')
                Multiple_Stepper_String = append("1,",int2str(160),",","2,",int2str(0));
                write(s,Multiple_Stepper_String,'string');
            else
                disp('swap 10-11')
                Multiple_Stepper_String = append("1,",int2str(0),",","2,",int2str(160));
                write(s,Multiple_Stepper_String,'string');
            end
            pause(10)
        end
        
        % function toDump(~, a)
        %     Servo = servo(a,'D9','MinPulseDuration',700*10^-6,'MaxPulseDuration',2300*10^-6);
        %     writePosition(Servo, 50)
        % end
               
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ledArduino = arduino('COM6');
            app.towerPort = serialport('COM7', 9600);
            app.moverPort = serialport('COM3', 9600);
            app.swapPort = serialport('COM5', 9600);
        end

        % Button pushed function: StartGameButton
        function StartGameButtonPushed(app, event)
            app.StartGameButton.Visible = "off";

            clc            
            BoardSetup=readmatrix('StartingBoardSetup.xlsx');

            % Create a fourth column that is the sum of columns 2 and 3 so that we know
            % if a space is available

            SpaceAvailable=(BoardSetup(:,2)+BoardSetup(:,3));
            BoardSetup=[BoardSetup,SpaceAvailable];
            app.GameSetup.Data = BoardSetup;

            % In this setup the position numbers are the first column, the postion of
            % the red pieces are the second column and the position of the yellow pieces
            % are the third column.

            %***********************************************************************
            % SECTION 1 - Set score to zero and enter while loop
            %***********************************************************************
            % Start the game. 
            % Wining condition: A player reach score of 4.
           
            ScorePlayer1=0;
            ScorePlayer2=0;
            turnNum=0;
            Char1 = { };
            while ScorePlayer1 < 2 | ScorePlayer2 < 2
                clc;
                toSwap(app, app.swapPort, 10)
                toSwap(app, app.swapPort, 3)
                % Check whose turn it is
                if rem(turnNum,2)==0 % Turn number is EVEN
                    ColOfInterest=3;
                    Char1{end+1,1}=char(sprintf('Player 1, your turn, you are playing yellow. \n'));

                    writeDigitalPin (app.ledArduino,'D12',1) ;
                    pause (3) ;
                    writeDigitalPin (app.ledArduino,'D12',0) ;
                else
                    ColOfInterest=2;
                    Char1{end+1,1}=char(sprintf('Player 2, your turn, you are playing red. \n'));

                    writeDigitalPin (app.ledArduino,'D13',1) ;
                    pause (3) ;
                    writeDigitalPin (app.ledArduino,'D13',0) ;
                end


                % **** Create bar graph ***
                axesHandle = findall(app.UIFigure, 'Tag', 'Graph');
                bar(axesHandle, BoardSetup(:,1), BoardSetup(:,2), 'r');
                hold(axesHandle, 'on');
                bar(axesHandle, BoardSetup(:,1), BoardSetup(:,3), 'y');
                hold(axesHandle, 'off');


                % ******************************************************************
                %  Section 2 - rolling the dice and checking for legal moves
                %*******************************************************************

                % now let me roll a dice and get a random value, integer between 1 and 6
                MoveComplete=0;
                dice = rollDice(app);
                if dice == 1
                    app.Image.ImageSource=imread('Dice 1.jpg');
                elseif dice==2
                    app.Image.ImageSource=imread('Dice 2.jpg');
                elseif dice==3
                    app.Image.ImageSource=imread('Dice 3.jpg');
                elseif dice==4
                    app.Image.ImageSource=imread('Dice 4.jpg');
                elseif dice==5
                    app.Image.ImageSource=imread('Dice 5.jpg');
                elseif dice==6
                    app.Image.ImageSource=imread('Dice 6.jpg');
                else
                    disp('failed read dice');
                end

                app.DicerollEditField.Value = dice;
                Char1{end+1,1}=char(sprintf('Dice rolled a %d. \n', dice));

                %***********************************************************************

                % Now I want to find all the legal moves for the player given that dice
                % roll, and display them to that player

                %***********************************************************************
                % Regular moving a piece on the board forward
                %***********************************************************************
                opportunities=0;
                for i= 1:12
                    checkPosition=i;
                    if BoardSetup(i,ColOfInterest)>=1
                        possibleMove=i+dice; % the piece will be that many spaces from where the piece is now
                        if possibleMove >= 12 % Past the last position on the board
                            Char1{end+1,1}=char(sprintf('Game piece in postion %d can move to home \n', checkPosition));
                            opportunities=opportunities+1;
                        elseif BoardSetup(possibleMove,4) == 0
                            Char1{end+1,1}=char(sprintf('Game piece in position %d can move to position %d \n', checkPosition, possibleMove));
                            opportunities=opportunities+1;
                        end
                    end
                end
                app.GameMessageTextArea.Value = Char1;

                %***********************************************************************
                % check to see if the player can get a man out
                GamePiecesOut=sum(BoardSetup(:,ColOfInterest));
                if GamePiecesOut<4 && BoardSetup((dice+1),4)==0
                    Char1{end+1,1}=char(sprintf('Enter "1" to start a new game piece \n'));
                    opportunities=opportunities+1;
                end
                app.GameMessageTextArea.Value = Char1;

                %**********************************************************************
                % Check that there is a valid move for this player. If not turn passes to
                % next player.

                if opportunities==0
                    Char1{end+1,1}=char(sprintf('You have 0 options. Turn passes to next player. \n'));
                    MoveComplete=1;
                end
                app.GameMessageTextArea.Value = Char1;

                %****************************************************************
                % Section 3 - PROMPT USER TO MOVE Pieces
                %****************************************************************

                while MoveComplete==0
                    uiwait;
                    Piece2Move = app.MovePieceSpinner.Value;

                    if BoardSetup(Piece2Move,ColOfInterest)==1 && (Piece2Move + dice) >= 12
                        BoardSetup(Piece2Move,ColOfInterest)=0;                        
                        if ColOfInterest==3
                            ScorePlayer1=ScorePlayer1+1;
                            app.Scoreplayer1EditField.Value = ScorePlayer1;

                            Char1{end+1,1}=char(sprintf('Score! Player 1 score %d, Player 2 score %d. \n',ScorePlayer1, ScorePlayer2));
                        else
                            ScorePlayer2=ScorePlayer2+1;
                            app.Scoreplayer2EditField.Value = ScorePlayer2;
                            Char1{end+1,1}=char(sprintf('Score! Player 1 score %d, Player 2 score %d. \n',ScorePlayer1, ScorePlayer2));
                        end
                        MoveComplete=1;
                    elseif BoardSetup(Piece2Move,ColOfInterest)==1 && BoardSetup((Piece2Move+dice),4)==0
                        Char1{end+1,1}=char(sprintf('Valid Move \n'));
                        BoardSetup(Piece2Move,ColOfInterest)=0;

                        NewPosition = Piece2Move + dice;
                        BoardSetup(NewPosition,ColOfInterest) = 1;

                        MoveComplete=1;
                    elseif Piece2Move==1 && BoardSetup((Piece2Move+dice),4)==0
                        Char1{end+1,1}=char(sprintf('Red or yellow released a new piece at position ... \n'));
                        BoardSetup(Piece2Move,ColOfInterest)=0;

                        NewPosition=Piece2Move+dice;
                        BoardSetup(NewPosition,ColOfInterest)=1;
                        
                        MoveComplete=1;
                    else
                        Char1{end+1,1}=char(sprintf('Invalid Move, please try again \n'));
                        MoveComplete=0;

                    end
                end
                BoardSetup(:,4)=(BoardSetup(:,2)+BoardSetup(:,3));
                app.GameMessageTextArea.Value = Char1;

                %**********************************************************************
                % Section 4 - CONNECT PHYSICAL SUBSYS TO MOVE GAME PIECES
                %**********************************************************************
                
                % ************** SECTION 4.1 GRAB GAME PIECE ********
                %disp('Grab game piece')
                % 1) Move tower to the pick-up position
                disp('move to pick')
                moveTower2Pick(app, app.towerPort, Piece2Move);    
                
               %  %1-2) Check for positional accuracy
               %  %2) Open the claw               
               moveClaw(app, app.moverPort, app.steps_for_opening)                
                % 3) Lower the game-piece mover
                moveBoom(app, app.moverPort, app.steps_for_lowering)                
                %4) Check for position of end effector and game piece
                %5) Close the claw
                moveClaw(app, app.moverPort, app.steps_for_closing)                
                %6) Raise the game-piece mover
                moveBoom(app, app.moverPort, app.steps_for_raising)                
               disp('finish')

                % ************** SECTION 4.2 DROP GAME PIECE ********
               % disp('drop game piece')
                %1. Rotate the tower to drop-in position = current pos +
                %dice
                disp('move 2 drop')
                moveTower2Drop(app, app.towerPort, Piece2Move+dice);
                
                %2. Check for positional accuracy
                %3. Lower the game-piece mover
                moveBoom(app, app.moverPort, app.steps_for_lowering)
                %4. Check for positional accuracy
                %5. Open the claw
                moveClaw(app, app.moverPort, app.steps_for_opening)
                %6. Check for positional accuracy
                %7. Raise the game-piece mover
                moveBoom(app, app.moverPort, app.steps_for_raising)
                %8. Close the claw
                moveClaw(app, app.moverPort, app.steps_for_closing)

                % ************** SECTION 4.3 RETURN TOWER BACK HOME********
                %1. Check position of tower
                %2. Check claw
                %3. Rotate tower back to home
                disp('back to home')
                moveTowerHome(app, app.towerPort, Piece2Move+dice)

                %**********************************************************************
                % Section 4 - SWAP AND DUMP
                %**********************************************************************                
                Pos3Rand=randi(4);
                if Pos3Rand==2
                    %swap the pieces currently in positions 3 and 4
                    if BoardSetup(3,4)==1 || BoardSetup(4,4)==1
                        name1 = ternaryOperator(app, BoardSetup(3,2), BoardSetup(3,3));
                        name2 = ternaryOperator(app, BoardSetup(4,2), BoardSetup(4,3));

                        PlaceHolder=BoardSetup(3,2:3);
                        BoardSetup(3,2:3)=BoardSetup(4,2:3);
                        BoardSetup(4,2:3)=PlaceHolder;
                        Char1{end+1,1}=char(sprintf('Positions 3(%s) and 4(%s) swapped. \n', name1, name2));
                        
                        pause(2)
                        toSwap(app, app.swapPort, 3)
                    end
                end

                Pos9Rand=randi(4);
                if Pos9Rand==2
                    %swap the pieces currently in positions 10 and 11
                    if BoardSetup(9,4)==1 || BoardSetup(10,4)==1
                        name1 = ternaryOperator(app, BoardSetup(9,2), BoardSetup(9,3));
                        name2 = ternaryOperator(app, BoardSetup(10,2), BoardSetup(10,3));

                        PlaceHolder=BoardSetup(9,2:3);
                        BoardSetup(9,2:3)=BoardSetup(10,2:3);
                        BoardSetup(10,2:3)=PlaceHolder;
                        Char1{end+1,1}=char(sprintf('Positions 9(%s) and 10(%s) swapped. \n', name1, name2));
                        
                        pause(2)
                        toSwap(app, app.swapPort, 10)
                    end
                end

                %*********************************************************************
                % DUMP
                % ********************************************************************
                % Pos6Rand=randi(4);
                % if Pos6Rand==2
                %     % Dump the game piece and it goes back to start
                %     BoardSetup(6,2:3)=0;
                %     Char1{end+1,1}=char(sprintf('Position 6 has been dumped, of player red/blue/0 \n'));
                % 
                %     pause(2)
                %     %toDump(app, app.port1);
                % end
                
                turnNum=turnNum+1;
                app.GameSetup.Data = BoardSetup;
                pause(4);
                Char1 = { };
            end

            % *********************************************************************
            % Declare a winner!
            % ******************************************************************
            Char1{end+1,1}=char(sprintf('Game Over!  Player 1 has %d goals, Player 2 has %d goals! \n',ScorePlayer1, ScorePlayer2));
            app.GameMessageTextArea.Value = Char1;
        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
            %app.MoveButton.BackgroundColor='red';
            uiresume;
            app.MoveButton.Visible = "off";
        end

        % Value changed function: MovePieceSpinner
        function MovePieceSpinnerValueChanged(app, event)
            app.MoveButton.Visible = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Map')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [1 12];
            app.UIAxes.YLim = [0 2];
            app.UIAxes.XTick = [1 2 3 4 5 6 7 8 9 10 11 12];
            app.UIAxes.XTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'};
            app.UIAxes.YTick = [0 1 2];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ZTick = [];
            app.UIAxes.Tag = 'Graph';
            app.StartGameButton = uibutton(app.UIFigure, 'push');
            app.StartGameButton.ButtonPushedFcn = createCallbackFcn(app, @StartGameButtonPushed, true);
            app.StartGameButton.Text = 'Start Game';
            app.GameMessageTextAreaLabel = uilabel(app.UIFigure);
            app.GameMessageTextAreaLabel.Text = 'Game Message';
            app.GameMessageTextArea = uitextarea(app.UIFigure);
            app.GameMessageTextArea.Editable = 'off';
            app.Scoreplayer1EditFieldLabel = uilabel(app.UIFigure);
            app.Scoreplayer1EditFieldLabel.Text = 'Score player 1';
            app.Scoreplayer1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Scoreplayer2EditFieldLabel = uilabel(app.UIFigure);
            app.Scoreplayer2EditFieldLabel.Text = 'Score player 2';
            app.Scoreplayer2EditField = uieditfield(app.UIFigure, 'numeric');
            app.DicerollEditFieldLabel = uilabel(app.UIFigure);
            app.DicerollEditFieldLabel.Text = 'Dice roll';
            app.DicerollEditField = uieditfield(app.UIFigure, 'numeric');
            app.DicerollEditField.Editable = 'off';
            app.MoveButton = uibutton(app.UIFigure, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Visible = 'off';
            app.MoveButton.Text = 'Move';
            app.MovePieceSpinnerLabel = uilabel(app.UIFigure);
            app.MovePieceSpinnerLabel.Text = 'Move Piece';
            app.MovePieceSpinner = uispinner(app.UIFigure);
            app.MovePieceSpinner.ValueChangedFcn = createCallbackFcn(app, @MovePieceSpinnerValueChanged, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'dice roller.png');
            app.GameSetup = uitable(app.UIFigure);
            app.GameSetup.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.GameSetup.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end